classdef ExtractionAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ExtractantSelectionPanel       matlab.ui.container.Panel
        TOPOwtEditFieldLabel           matlab.ui.control.Label
        TOPOwtEditField                matlab.ui.control.NumericEditField
        PropiophenonewtEditFieldLabel  matlab.ui.control.Label
        PropiophenonewtEditField       matlab.ui.control.NumericEditField
        MineralOilwtEditFieldLabel     matlab.ui.control.Label
        MineralOilwtEditField          matlab.ui.control.NumericEditField
        CheckboxifusingCyanex923CheckBox  matlab.ui.control.CheckBox
        FermentationandContactorSettingsPanel  matlab.ui.container.Panel
        pHEditFieldLabel               matlab.ui.control.Label
        pHEditField                    matlab.ui.control.NumericEditField
        VolumeRatioORGAQLabel          matlab.ui.control.Label
        VolumeRatioORGAQEditField      matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel     matlab.ui.control.Label
        TemperatureCEditField          matlab.ui.control.NumericEditField
        AcidSelectionPanel             matlab.ui.container.Panel
        AceticAcidCheckBox             matlab.ui.control.CheckBox
        PropionicAcidCheckBox          matlab.ui.control.CheckBox
        ButyricAcidCheckBox            matlab.ui.control.CheckBox
        ValericAcidCheckBox            matlab.ui.control.CheckBox
        CaproicAcidCheckBox            matlab.ui.control.CheckBox
        PlotTypeDropDownLabel          matlab.ui.control.Label
        PlotTypeDropDown               matlab.ui.control.DropDown
        AcidConcentrationsPanel        matlab.ui.container.Panel
        AceticgLEditFieldLabel         matlab.ui.control.Label
        AceticgLEditField              matlab.ui.control.NumericEditField
        PropionicgLEditFieldLabel      matlab.ui.control.Label
        PropionicgLEditField           matlab.ui.control.NumericEditField
        ButyricgLEditFieldLabel        matlab.ui.control.Label
        ButyricgLEditField             matlab.ui.control.NumericEditField
        ValericgLEditFieldLabel        matlab.ui.control.Label
        ValericgLEditField             matlab.ui.control.NumericEditField
        CaproicgLEditFieldLabel        matlab.ui.control.Label
        CaproicgLEditField             matlab.ui.control.NumericEditField
        PLOTButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
    end


    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function uifigure(app)
            
        end

        % Value changed function: AceticAcidCheckBox
        function AceticAcidCheckBoxValueChanged(app, event)
            value = app.AceticAcidCheckBox.Value;
            
            
        end

        % Value changed function: AceticgLEditField
        function AceticgLEditFieldValueChanged(app, event)
            value = app.AceticgLEditField.Value;
            
        end

        % Size changed function: AcidConcentrationsPanel
        function AcidConcentrationsPanelSizeChanged(app, event)
            position = app.AcidConcentrationsPanel.Position;
            
        end

        % Size changed function: AcidSelectionPanel
        function AcidSelectionPanelSizeChanged(app, event)
            position = app.AcidSelectionPanel.Position;
            
        end

        % Value changed function: ButyricAcidCheckBox
        function ButyricAcidCheckBoxValueChanged(app, event)
            value = app.ButyricAcidCheckBox.Value;
            
        end

        % Value changed function: ButyricgLEditField
        function ButyricgLEditFieldValueChanged(app, event)
            value = app.ButyricgLEditField.Value;
            
        end

        % Value changed function: CaproicAcidCheckBox
        function CaproicAcidCheckBoxValueChanged(app, event)
            value = app.CaproicAcidCheckBox.Value;
            
        end

        % Value changed function: CaproicgLEditField
        function CaproicgLEditFieldValueChanged(app, event)
            value = app.CaproicgLEditField.Value;
            
        end

        % Value changed function: CheckboxifusingCyanex923CheckBox
        function CheckboxifusingCyanex923CheckBoxValueChanged(app, event)
            value = app.CheckboxifusingCyanex923CheckBox.Value;
            
        end

        % Size changed function: ExtractantSelectionPanel
        function ExtractantSelectionPanelSizeChanged(app, event)
            position = app.ExtractantSelectionPanel.Position;
            
        end

        % Size changed function: FermentationandContactorSettingsPanel
        function FermentationandContactorSettingsPanelSizeChanged(app, event)
            position = app.FermentationandContactorSettingsPanel.Position;
            
        end

        % Value changed function: MineralOilwtEditField
        function MineralOilwtEditFieldValueChanged(app, event)
            value = app.MineralOilwtEditField.Value;
            
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            dropdown=app.PlotTypeDropDown.Value;
            size(dropdown)
         
            if size(dropdown)==[1 28]  %If Mixed Acid Loading Vs. Titer Tab is selected
            
            %% Visibility of graphs and input fields
            app.ExtractantSelectionPanel.Visible='on';
            app.FermentationandContactorSettingsPanel.Visible='on';
            app.AcidSelectionPanel.Visible='on';
            app.pHEditField.Visible='on';
            app.AcidConcentrationsPanel.Visible='off';
            app.UIAxes2.Visible='on';
            app.VolumeRatioORGAQEditField.Visible='on';
            cla(app.UIAxes2);
            cla(app.UIAxes);
            
            %%Define Inputs to model
            MM=[60.05,74.07,88.11,102.13,116.16];  %Molar mass of C2-C6 (g/mol)
            Ka=[1.8*10^-5,1.34*10^-5,1.51*10^-5,1.4*10^-5,1.4*10^-5]; %Acid disassociation constants of C2-C6
            C2=app.AceticAcidCheckBox.Value; % User-input: if acetic acid is included in system, value==1, else value==0
            C3=app.PropionicAcidCheckBox.Value; % User-input: if propionic acid is included in system, value==1, else value==0
            C4=app.ButyricAcidCheckBox.Value; % User-input: if butyric acid is included in system, value==1, else value==0
            C5=app.ValericAcidCheckBox.Value; %% User-input: if valeric acid is included in system, value==1, else value==0
            C6=app.CaproicAcidCheckBox.Value; % User-input: if caproic acid is included in system, value==1, else value==0
            VR=app.VolumeRatioORGAQEditField.Value;  %User-input for organic to aqueous volume ratio
            pH=app.pHEditField.Value;  % User-input for pH
            T=app.TemperatureCEditField.Value; %User-input value for temperature
            TOPOwt=app.TOPOwtEditField.Value;   % User-input for wt% TOPO in extractant
            Propiophenonewt=app.PropiophenonewtEditField.Value;  % User-input for wt% propiophenone in extractant
            MineralOilwt=app.MineralOilwtEditField.Value;  % User-input for wt% mineral oil in extractant
            Density=[0.88,1.01,0.85];  % Density of TOPO, propiophenone, and mineral oil, respectively
            Solventwt=[TOPOwt,Propiophenonewt,MineralOilwt]; %Solvent wt % in vector
            vf=Solventwt.*Density./sum(Solventwt.*Density);  %Calculate volume percent of each solvent
            
            if app.CheckboxifusingCyanex923CheckBox.Value==1  % If Cyanex 923 is used, phosphine oxide concentration is higher than pure TOPO
            TOPOi=2.526;  % Molarity of phosphine oxides in Cyanex 923
            else
            TOPOi=2.276;  %Molar density of pure TOPO
            end
         
            KEQext=[2.91,11.56,32.25,147.92,276.01].*exp((12.56/0.0083144)*(1/(T+273.15)-1/295.15)); %Calulate KEQ,EXT as a function of temperature using experimentally determined enthalpy value
          
            % Select KD values depending on user-selection of solvents
            if Propiophenonewt>0 && MineralOilwt==0
                KD=[0.2,0.8,3,10.4,45];
                KD1=[0,0,0,0,0];
            elseif MineralOilwt>0 && Propiophenonewt==0
                KD=[0,0,0,0,0];
                KD1=[0.007,0.017,0.2,0.8,1.2];
            elseif Propiophenonewt>0 && MineralOilwt>0
                KD=[0.2,0.8,3,10.4,45];
                KD1=[0.007,0.017,0.2,0.8,1.2];
            elseif Propiophenonewt==0 && MineralOilwt==0
                KD=[0,0,0,0,0];
                KD1=[0,0,0,0,0];
            end
            
            %%Set Initial Guesses
             iterations=71;
            
            for i=1:iterations
                %%iterate in small range of acid concentrations with random initial guess to develop solutions to supply more accurate initial guesses for the remainder of iterations
                if i>0 && i<(iterations-49)   
                    HAi=[i*C2,i*C3,i*C4,i*C5,i*C6]./MM./(iterations-51);
                else
                    HAi=[C2*(i-(iterations-50)),C3*(i-(iterations-50)),C4*(i-(iterations-50)),C5*(i-(iterations-50)),C6*(i-(iterations-50))]./MM;
                end
                 
                if i==1   %For first few iterations supply random zero initial guess
                    x0=zeros(1,23);
                    x0(11)=TOPOi;
                    x0(17)=10^(-pH); %Including an initial guess for pH helps reduce error
                else
                    x0=X(i-1,:);  % Use solution from previous iteration as initial guess
                end
             
              %% Supply inputs and initial guesses into system equation (see extraction.m) and solve using fsolve
              options=optimoptions('fsolve','FunctionTolerance',1E-20,'OptimalityTolerance',1E-20,'StepTolerance',1E-20,'MaxFunctionEvaluation',1E5,'MaxIter',10000,'Display','off');
              [x]=fsolve(@(x) extraction(x,KEQext,KD,Ka,TOPOi,HAi,VR,pH,KD1,vf),x0,options);
              
                
               if x(x<-0.001)
                   X(i,:)=zeros(1,23);  %if solution is negative, skip this iteration
               else
                   Corg(:,i)=(vf(1).*x(1:5)+vf(2).*x(12:16)+vf(3).*x(6:10)).*MM;  %Calculate organic loading of acid in extractant using linear summation of extraction by the 3 solvents (g/L)
                   HAi1(:,i)=HAi.*MM;  %Store initial concentrations in matrix
                
                   X(i,:)=x;  %Store all solutions from fsolve to use as inital guess for next iteration
               end
                      PR=(1-(HAi1-VR.*Corg)./HAi1)*100;  %Calculate percent recovery of acid
              
            end
                 
                %%Plotting
                plot(app.UIAxes,HAi1(1,:),Corg(1,:),HAi1(2,:),Corg(2,:),HAi1(3,:),Corg(3,:),HAi1(4,:),Corg(4,:),HAi1(5,:),Corg(5,:))
                legend(app.UIAxes,'Acetic','Propionic','Butyric','Valeric','Caproic','Location','bestoutside')
                xlim(app.UIAxes,[0 inf]);
                ylim(app.UIAxes,[0 inf]);
                xlabel(app.UIAxes, 'Acid Titer of Broth at Contactor Inlet (g/L)')
                ylabel(app.UIAxes, 'EQ Acid Concentration in Organic Phase (g/L)')
                
                plot(app.UIAxes2,HAi1(1,:),PR(1,:),HAi1(2,:),PR(2,:),HAi1(3,:),PR(3,:),HAi1(4,:),PR(4,:),HAi1(5,:),PR(5,:))
                legend(app.UIAxes2,'Acetic','Propionic','Butyric','Valeric','Caproic','Location','bestoutside')
                xlim(app.UIAxes2,[0 inf]);
                ylim(app.UIAxes2,[0 inf]);
                xlabel(app.UIAxes2, 'Acid Titer of Broth at Contactor Inlet (g/L)')
                ylabel(app.UIAxes2, 'Percent Recovery From Aqueous Stream (%)')
                app.UIAxes2.XTickMode = 'auto';
                app.UIAxes2.YTickMode = 'auto';


            elseif size(dropdown)==[1 33]  %If Fixed Mixed Acid: Loading vs. pH Tab is selected
             
            %% Visibility of graphs and input fields
            app.FermentationandContactorSettingsPanel.Visible='on';
            app.AcidSelectionPanel.Visible='off';
            app.AcidConcentrationsPanel.Visible='on';
            app.pHEditField.Visible='off';
            app.UIAxes2.Visible='on';
            app.VolumeRatioORGAQEditField.Visible='on';
            cla(app.UIAxes2);
            cla(app.UIAxes);
           
            %% Define Inputs to model (see above for details for each input if not defined here)
            MM=[60.05,74.07,88.11,102.13,116.16];
            Ka=[1.8*10^-5,1.34*10^-5,1.51*10^-5,1.4*10^-5,1.4*10^-5]; 
            C2=app.AceticgLEditField.Value; % User-input for acetic acid concentration (g/L)    
            C3=app.PropionicgLEditField.Value; % User-input for propionic acid concentration (g/L)
            C4=app.ButyricgLEditField.Value; % User-input for butyric acid concentration (g/L)
            C5=app.ValericgLEditField.Value; % User-input for valeric acid concentration (g/L)
            C6=app.CaproicgLEditField.Value; % User-input for caproic acid concentration (g/L)
            VR=app.VolumeRatioORGAQEditField.Value;
            TOPOwt=app.TOPOwtEditField.Value;   
            T=app.TemperatureCEditField.Value;
            Propiophenonewt=app.PropiophenonewtEditField.Value;
            MineralOilwt=app.MineralOilwtEditField.Value;
            Density=[0.88,1.01,0.85];
            Solventwt=[TOPOwt,Propiophenonewt,MineralOilwt];
            vf=Solventwt.*Density./sum(Solventwt.*Density);
            HAi=[C2,C3,C4,C5,C6]./MM; %Store user-defined concentrations for each acid into vector and conver to molar units
            
            if app.CheckboxifusingCyanex923CheckBox.Value==1
                TOPOi=2.526;
            else
                TOPOi=2.276;
            end
           
            KEQext=[2.91,11.56,32.25,147.92,276.01].*exp((12.56/0.0083144)*(1/(T+273.15)-1/295.15));
            
             if Propiophenonewt>0 && MineralOilwt==0
                KD=[0.2,0.8,3,10.4,45];
                KD1=[0,0,0,0,0];
            elseif MineralOilwt>0 && Propiophenonewt==0
                KD=[0,0,0,0,0];
                KD1=[0.007,0.017,0.2,0.8,1.2];
            elseif Propiophenonewt>0 && MineralOilwt>0
                KD=[0.2,0.8,3,10.4,45];
                KD1=[0.007,0.017,0.2,0.8,1.2];
            else
                KD=[0,0,0,0,0];
                KD1=[0,0,0,0,0];
            end
            
            %%Set Initial Guesses
            iterations=90;
            for i=1:iterations  %Iterate through pH values 
                
                if i>0 && i<(iterations-70)
                   pH=1+i/(iterations-70);
                else
                    pH=i/(iterations-80);
                end
              
                if i==1
                    x0=zeros(1,23);
                    x0(17)=10^(-pH);
                else
                    x0=X(i-1,:);
                end
                
              options=optimoptions('fsolve','FunctionTolerance',1E-20,'OptimalityTolerance',1E-20,'StepTolerance',1E-20,'MaxFunctionEvaluation',1E5,'MaxIter',10000);
              [x]=fsolve(@(x) extraction(x,KEQext,KD,Ka,TOPOi,HAi,VR,pH,KD1,vf),x0,options);
              
               if x(x<-0.001)
                   X(i,:)=zeros(1,23);
               else
                   Corg(:,i)=(vf(1).*x(1:5)+vf(2).*x(12:16)+vf(3).*x(6:10)).*MM;
                   pHf(i)=-log10(x(17));  %Calculate final pH of aqueous phase
                   pH1(i)=pH;
                   X(i,:)=x;
               end
              
            end
            
            %% Plotting
                plot(app.UIAxes,pH1,Corg(1,:),pH1,Corg(2,:),pH1,Corg(3,:),pH1,Corg(4,:),pH1,Corg(5,:))
                legend(app.UIAxes,'Acetic','Propionic','Butyric','Valeric','Caproic','Location','bestoutside')
                xlim(app.UIAxes,[1 inf]);
                ylim(app.UIAxes,[0 inf]); 
                xlabel(app.UIAxes, 'Fermentation pH')
                ylabel(app.UIAxes, 'EQ Concentration in Organic Phase (g/L)')
                
                x=linspace(1,pH1(end));
                y=x;
                plot(app.UIAxes2,pH1,pHf,x,y,'--r');
                xlim(app.UIAxes2,[1 inf]);
                ylim(app.UIAxes2,[1 inf]); 
                xlabel(app.UIAxes2,  'pH at Inlet of Contactor')
                ylabel(app.UIAxes2, 'pH at Outlet of Contactor')
                app.UIAxes2.XTickMode = 'auto';
                app.UIAxes2.YTickMode = 'auto';
                legend(app.UIAxes2,'Model','No Change','Location','bestoutside')
            
            elseif size(dropdown)==[1 42] %If Fixed Mixed Acid: Loading vs. Volume Ratio Tab is selected
             
            %% Visibility of graphs and input fields
            app.FermentationandContactorSettingsPanel.Visible='on';
            app.AcidSelectionPanel.Visible='off';
            app.AcidConcentrationsPanel.Visible='on';
            app.pHEditField.Visible='on';
            app.UIAxes2.Visible='on';
            cla(app.UIAxes2);
            cla(app.UIAxes);
            app.VolumeRatioORGAQEditField.Visible='off';
            
            %% Define Inputs to model (see above for details for each input if not defined here)
            MM=[60.05,74.07,88.11,102.13,116.16];
            Ka=[1.8*10^-5,1.34*10^-5,1.51*10^-5,1.4*10^-5,1.4*10^-5]; 
            C2=app.AceticgLEditField.Value;    
            C3=app.PropionicgLEditField.Value;
            C4=app.ButyricgLEditField.Value;
            C5=app.ValericgLEditField.Value;
            C6=app.CaproicgLEditField.Value;
            TOPOwt=app.TOPOwtEditField.Value;   
            pH=app.pHEditField.Value;
            T=app.TemperatureCEditField.Value;
            Propiophenonewt=app.PropiophenonewtEditField.Value;
            MineralOilwt=app.MineralOilwtEditField.Value;
            Density=[0.88,1.01,0.85];
            Solventwt=[TOPOwt,Propiophenonewt,MineralOilwt];
            vf=Solventwt.*Density./sum(Solventwt.*Density);
            HAi=[C2,C3,C4,C5,C6]./MM;
            
            if app.CheckboxifusingCyanex923CheckBox.Value==1
                TOPOi=2.526;
            else
                TOPOi=2.276;
            end
           
            KEQext=[2.91,11.56,32.25,147.92,276.01].*exp((12.56/0.0083144)*(1/(T+273.15)-1/295.15));
            
            
             if Propiophenonewt>0 && MineralOilwt==0
                KD=[0.2,0.8,3,10.4,45];
                KD1=[0,0,0,0,0];
            elseif MineralOilwt>0 && Propiophenonewt==0
                KD=[0,0,0,0,0];
                KD1=[0.007,0.017,0.2,0.8,1.2];
            elseif Propiophenonewt>0 && MineralOilwt>0
                KD=[0.2,0.8,3,10.4,45];
                KD1=[0.007,0.017,0.2,0.8,1.2];
            else
                KD=[0,0,0,0,0];
                KD1=[0,0,0,0,0];
            end
            
            %%Set Initial Guesses
            iterations=201;
            
            for i=1:iterations   %Iterate through volume ratios
                
                if i>0 && i<202
                   VR=(201-i)/200;
                else
                    VR=(201-i)/200;
                end
              
                if i==1
                    x0=zeros(1,23);
                    x0(17)=10^(-pH);
                else
                    x0=X(i-1,:);
                end
                
              options=optimoptions('fsolve','FunctionTolerance',1E-20,'OptimalityTolerance',1E-20,'StepTolerance',1E-20,'MaxFunctionEvaluation',1E5,'MaxIter',10000);
              [x]=fsolve(@(x) extraction(x,KEQext,KD,Ka,TOPOi,HAi,VR,pH,KD1,vf),x0,options);
              
               if x(x<-0.001)
                   X(i,:)=zeros(1,23);
               else
                   Corg(:,i)=(vf(1).*x(1:5)+vf(2).*x(12:16)+vf(3).*x(6:10)).*MM;
                   VR1(i)=VR;
                   X(i,:)=x;
               end
            end
                HAi=HAi.*MM;
                VR11=repmat(VR1,5,1);
                HAi1=repmat(HAi',1,length(VR11));
                PR=(1-(HAi1-VR11.*Corg)./HAi1)*100;
                
                %%Plotting
                plot(app.UIAxes,VR1,Corg(1,:),VR1,Corg(2,:),VR1,Corg(3,:),VR1,Corg(4,:),VR1,Corg(5,:))
                legend(app.UIAxes,'Acetic','Propionic','Butyric','Valeric','Caproic','Location','bestoutside')
                xlim(app.UIAxes,[0 inf]);
                ylim(app.UIAxes,[0 inf]); 
                xlabel(app.UIAxes, 'Volume Ratio (Vorg/Vaq)')
                ylabel(app.UIAxes, 'EQ Concentration in Organic Phase (g/L)')
                
                plot(app.UIAxes2,VR1(1,:),PR(1,:),VR1(1,:),PR(2,:),VR1(1,:),PR(3,:),VR1(1,:),PR(4,:),VR1(1,:),PR(5,:))
                legend(app.UIAxes2,'Acetic','Propionic','Butyric','Valeric','Caproic','Location','bestoutside')
                xlim(app.UIAxes2,[0 inf]);
                ylim(app.UIAxes2,[0 inf]);
                xlabel(app.UIAxes2, 'Volume Ratio (Vorg/Vaq)')
                ylabel(app.UIAxes2, 'Percent Recovery From Aqueous Stream (g/L)')
                app.UIAxes2.XTickMode = 'auto';
                app.UIAxes2.YTickMode = 'auto';


            elseif size(dropdown)==[1 35] %If Fixed Mixed Acid: Tabulated Results Tab is selected
            
            %% Visibility of graphs and input fields
            app.FermentationandContactorSettingsPanel.Visible='on';
            app.AcidSelectionPanel.Visible='off';
            app.AcidConcentrationsPanel.Visible='on';
            app.pHEditField.Visible='on';
            app.UIAxes2.Visible='off';
            cla(app.UIAxes2);
            app.UIAxes.Visible='off';
            cla(app.UIAxes);
            app.VolumeRatioORGAQEditField.Visible='on';
            app.UITable.Visible='on';
            app.UITable.ColumnWidth={65 110 115 120 70 50 50};
            app.PLOTButton.Text='TABULATE';
            
            %% Define Inputs to model (see above for details for each input if not defined here)
            MM=[60.05,74.07,88.11,102.13,116.16];
            Ka=[1.8*10^-5,1.34*10^-5,1.51*10^-5,1.4*10^-5,1.4*10^-5]; 
            C2=app.AceticgLEditField.Value;    
            C3=app.PropionicgLEditField.Value;
            C4=app.ButyricgLEditField.Value;
            C5=app.ValericgLEditField.Value;
            C6=app.CaproicgLEditField.Value;
            VR=app.VolumeRatioORGAQEditField.Value;
            pH=app.pHEditField.Value;
            T=app.TemperatureCEditField.Value;
            TOPOwt=app.TOPOwtEditField.Value;   
            Propiophenonewt=app.PropiophenonewtEditField.Value;
            MineralOilwt=app.MineralOilwtEditField.Value;
            Density=[0.88,1.01,0.85];
            Solventwt=[TOPOwt,Propiophenonewt,MineralOilwt];
            vf=Solventwt.*Density./sum(Solventwt.*Density);
            HAi=[C2,C3,C4,C5,C6]./MM;
            
            if app.CheckboxifusingCyanex923CheckBox.Value==1
                TOPOi=2.526;
            else
                TOPOi=2.276;
            end
            
            KEQext=[2.91,11.56,32.25,147.92,276.01].*exp((12.56/0.0083144)*(1/(T+273.15)-1/295.15));
           
            if Propiophenonewt>0 && MineralOilwt==0
                KD=[0.2,0.8,3,10.4,45];
                KD1=[0,0,0,0,0];
            elseif MineralOilwt>0 && Propiophenonewt==0
                KD=[0,0,0,0,0];
                KD1=[0.007,0.017,0.2,0.8,1.2];
            elseif Propiophenonewt>0 && MineralOilwt>0
                KD=[0.2,0.8,3,10.4,45];
                KD1=[0.007,0.017,0.2,0.8,1.2];
            else
                KD=[0,0,0,0,0];
                KD1=[0,0,0,0,0];
            end
            
            %%Set Initial Guesses
            x0=zeros(1,23);
            x0(17)=10^(-pH);
            options=optimoptions('fsolve','FunctionTolerance',1E-20,'OptimalityTolerance',1E-20,'StepTolerance',1E-20,'MaxFunctionEvaluation',1E5,'MaxIter',10000);
            [x]=fsolve(@(x) extraction(x,KEQext,KD,Ka,TOPOi,HAi,VR,pH,KD1,vf),x0,options);
            HAi=HAi.*MM;
            Corg=(vf(1).*x(1:5)+vf(2).*x(12:16)+vf(3).*x(6:10)).*MM;
            CAQf=HAi-VR*Corg; 
            KD=Corg./CAQf;
            
            for i=1:5
            if HAi(i)==0
            Corg(i)=0 ;
            CAQf(i)=0 ;
            KD(i)=0;
            end
            end
            
            % Convert from number to cell format for tabulation
            pHf=-log10(x(17)); 
            pHf=num2cell(pHf);
            KD=num2cell(KD);
            HAi=num2cell(HAi);
            pH=num2cell(pH);
            Corg=num2cell(Corg);
            CAQf=num2cell(CAQf);
           
           %% Tabulation 
            Acid=['Acetic','Propionic','Butyric','Valeric','Caproic'];
            app.UITable.Data=[Acid(1:6),HAi(1),CAQf(1),Corg(1),KD(1),pH,pHf;...
                Acid(7:15),HAi(2),CAQf(2),Corg(2),KD(2),pH,pHf;...
                Acid(16:22),HAi(3),CAQf(3),Corg(3),KD(3),pH,pHf;...
                Acid(23:29),HAi(4),CAQf(4),Corg(4),KD(4),pH,pHf;...
                Acid(30:36),HAi(5),CAQf(5),Corg(5),KD(5),pH,pHf];
            end


        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            value = app.PlotTypeDropDown.Value;
            if size(value)==[1 28]
    
            app.ExtractantSelectionPanel.Visible='on';
            app.FermentationandContactorSettingsPanel.Visible='on';
            app.AcidSelectionPanel.Visible='on';
            app.pHEditField.Visible='on';
            app.AcidConcentrationsPanel.Visible='off';
            app.UIAxes2.Visible='off';
            app.UIAxes.Visible='on';
            cla(app.UIAxes2);
            app.VolumeRatioORGAQEditField.Visible='on';
            app.UITable.Visible='off';
            cla(app.UITable);
            app.PLOTButton.Text='PLOT';
          
            
            elseif size(value)==[1 33]
            
            app.FermentationandContactorSettingsPanel.Visible='on';
            app.AcidSelectionPanel.Visible='off';
            app.AcidConcentrationsPanel.Visible='on';
            app.pHEditField.Visible='off';
            app.UIAxes2.Visible='on';
            app.UIAxes.Visible='on';
            app.VolumeRatioORGAQEditField.Visible='on';
            app.UITable.Visible='off';
            cla(app.UITable);
            app.PLOTButton.Text='PLOT';
          
            
           elseif size(value)==[1 42]
            
            app.FermentationandContactorSettingsPanel.Visible='on';
            app.AcidSelectionPanel.Visible='off';
            app.AcidConcentrationsPanel.Visible='on';
            app.pHEditField.Visible='on';
            app.UIAxes2.Visible='off';
            app.UIAxes.Visible='on';
            cla(app.UIAxes2);
            app.VolumeRatioORGAQEditField.Visible='off';
            app.UITable.Visible='off';
            cla(app.UITable);
             app.PLOTButton.Text='PLOT';


            elseif size(value)==[1 35]
            
            app.FermentationandContactorSettingsPanel.Visible='on';
            app.AcidSelectionPanel.Visible='off';
            app.AcidConcentrationsPanel.Visible='on';
            app.pHEditField.Visible='on';
            app.UIAxes2.Visible='off';
            cla(app.UIAxes2);
            app.UIAxes.Visible='off';
            cla(app.UIAxes);
            app.VolumeRatioORGAQEditField.Visible='on';
            app.UITable.Visible='on';
            app.UITable.ColumnWidth={65 110 115 120 70 50 50};
             app.PLOTButton.Text='TABULATE';


            end
            
        end

        % Value changed function: PropionicAcidCheckBox
        function PropionicAcidCheckBoxValueChanged(app, event)
            value = app.PropionicAcidCheckBox.Value;
            
        end

        % Value changed function: PropionicgLEditField
        function PropionicgLEditFieldValueChanged(app, event)
            value = app.PropionicgLEditField.Value;
            
        end

        % Value changed function: PropiophenonewtEditField
        function PropiophenonewtEditFieldValueChanged(app, event)
            value = app.PropiophenonewtEditField.Value;
            
        end

        % Value changed function: TOPOwtEditField
        function TOPOwtEditFieldValueChanged(app, event)
            value = app.TOPOwtEditField.Value;
                         
        end

        % Value changed function: TemperatureCEditField
        function TemperatureCEditFieldValueChanged(app, event)
            value = app.TemperatureCEditField.Value;
           
        end

        % Value changed function: ValericAcidCheckBox
        function ValericAcidCheckBoxValueChanged(app, event)
            value = app.ValericAcidCheckBox.Value;
            
        end

        % Value changed function: ValericgLEditField
        function ValericgLEditFieldValueChanged(app, event)
            value = app.ValericgLEditField.Value;
            
        end

        % Value changed function: VolumeRatioORGAQEditField
        function VolumeRatioORGAQEditFieldValueChanged(app, event)
            value = app.VolumeRatioORGAQEditField.Value;
        end

        % Value changed function: pHEditField
        function pHEditFieldValueChanged(app, event)
            value = app.pHEditField.Value;
         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Single Pass Acid Loading')
            xlabel(app.UIAxes, 'Acid Titer of Broth at Contactor Inlet (g/L)')
            ylabel(app.UIAxes, 'EQ Acid Concentration in Extractant (g/L)')
            app.ExtractantSelectionPanel = uipanel(app.UIFigure);
            app.ExtractantSelectionPanel.Title = 'Extractant Selection';
            app.ExtractantSelectionPanel.SizeChangedFcn = createCallbackFcn(app, @ExtractantSelectionPanelSizeChanged, true);
            app.TOPOwtEditFieldLabel = uilabel(app.ExtractantSelectionPanel);
            app.TOPOwtEditFieldLabel.Text = 'TOPO (wt%)';
            app.TOPOwtEditField = uieditfield(app.ExtractantSelectionPanel, 'numeric');
            app.TOPOwtEditField.ValueChangedFcn = createCallbackFcn(app, @TOPOwtEditFieldValueChanged, true);
            app.TOPOwtEditField.Limits = [0 100];
            app.TOPOwtEditField.ValueDisplayFormat = '%.2f';
            app.TOPOwtEditField.Value = 100;
            app.PropiophenonewtEditFieldLabel = uilabel(app.ExtractantSelectionPanel);
            app.PropiophenonewtEditFieldLabel.Text = 'Propiophenone (wt%)';
            app.PropiophenonewtEditField = uieditfield(app.ExtractantSelectionPanel, 'numeric');
            app.PropiophenonewtEditField.ValueChangedFcn = createCallbackFcn(app, @PropiophenonewtEditFieldValueChanged, true);
            app.PropiophenonewtEditField.Limits = [0 100];
            app.PropiophenonewtEditField.ValueDisplayFormat = '%.2f';
            app.MineralOilwtEditFieldLabel = uilabel(app.ExtractantSelectionPanel);
            app.MineralOilwtEditFieldLabel.Text = 'Mineral Oil (wt%)';
            app.MineralOilwtEditField = uieditfield(app.ExtractantSelectionPanel, 'numeric');
            app.MineralOilwtEditField.ValueChangedFcn = createCallbackFcn(app, @MineralOilwtEditFieldValueChanged, true);
            app.MineralOilwtEditField.Limits = [0 100];
            app.MineralOilwtEditField.ValueDisplayFormat = '%.2f';
            app.CheckboxifusingCyanex923CheckBox = uicheckbox(app.ExtractantSelectionPanel);
            app.CheckboxifusingCyanex923CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckboxifusingCyanex923CheckBoxValueChanged, true);
            app.CheckboxifusingCyanex923CheckBox.Text = 'Check box if using Cyanex 923';
            app.FermentationandContactorSettingsPanel = uipanel(app.UIFigure);
            app.FermentationandContactorSettingsPanel.Title = 'Fermentation and Contactor Settings';
            app.FermentationandContactorSettingsPanel.SizeChangedFcn = createCallbackFcn(app, @FermentationandContactorSettingsPanelSizeChanged, true);
            app.pHEditFieldLabel = uilabel(app.FermentationandContactorSettingsPanel);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHEditField = uieditfield(app.FermentationandContactorSettingsPanel, 'numeric');
            app.pHEditField.ValueChangedFcn = createCallbackFcn(app, @pHEditFieldValueChanged, true);
            app.pHEditField.Limits = [1 14];
            app.pHEditField.ValueDisplayFormat = '%.1f';
            app.pHEditField.Value = 2.5;
            app.VolumeRatioORGAQLabel = uilabel(app.FermentationandContactorSettingsPanel);
            app.VolumeRatioORGAQLabel.Text = {'Volume Ratio'; ' (ORG/AQ)'};
            app.VolumeRatioORGAQEditField = uieditfield(app.FermentationandContactorSettingsPanel, 'numeric');
            app.VolumeRatioORGAQEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeRatioORGAQEditFieldValueChanged, true);
            app.VolumeRatioORGAQEditField.Limits = [0.01 10];
            app.VolumeRatioORGAQEditField.ValueDisplayFormat = '%.2f';
            app.VolumeRatioORGAQEditField.Value = 0.1;
            app.TemperatureCEditFieldLabel = uilabel(app.FermentationandContactorSettingsPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.FermentationandContactorSettingsPanel, 'numeric');
            app.TemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureCEditFieldValueChanged, true);
            app.TemperatureCEditField.Limits = [4 75];
            app.TemperatureCEditField.ValueDisplayFormat = '%.0f';
            app.TemperatureCEditField.Value = 22;
            app.AcidSelectionPanel = uipanel(app.UIFigure);
            app.AcidSelectionPanel.Title = 'Acid Selection ';
            app.AcidSelectionPanel.SizeChangedFcn = createCallbackFcn(app, @AcidSelectionPanelSizeChanged, true);
            app.AceticAcidCheckBox = uicheckbox(app.AcidSelectionPanel);
            app.AceticAcidCheckBox.ValueChangedFcn = createCallbackFcn(app, @AceticAcidCheckBoxValueChanged, true);
            app.AceticAcidCheckBox.Text = 'Acetic Acid';
            app.AceticAcidCheckBox.Value = true;
            app.PropionicAcidCheckBox = uicheckbox(app.AcidSelectionPanel);
            app.PropionicAcidCheckBox.ValueChangedFcn = createCallbackFcn(app, @PropionicAcidCheckBoxValueChanged, true);
            app.PropionicAcidCheckBox.Text = 'Propionic Acid';
            app.PropionicAcidCheckBox.Value = true;
            app.ButyricAcidCheckBox = uicheckbox(app.AcidSelectionPanel);
            app.ButyricAcidCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButyricAcidCheckBoxValueChanged, true);
            app.ButyricAcidCheckBox.Text = 'Butyric Acid';
            app.ButyricAcidCheckBox.Value = true;
            app.ValericAcidCheckBox = uicheckbox(app.AcidSelectionPanel);
            app.ValericAcidCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValericAcidCheckBoxValueChanged, true);
            app.ValericAcidCheckBox.Text = 'Valeric Acid';
            app.ValericAcidCheckBox.Value = true;
            app.CaproicAcidCheckBox = uicheckbox(app.AcidSelectionPanel);
            app.CaproicAcidCheckBox.ValueChangedFcn = createCallbackFcn(app, @CaproicAcidCheckBoxValueChanged, true);
            app.CaproicAcidCheckBox.Text = 'Caproic Acid';
            app.CaproicAcidCheckBox.Value = true;
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDown.Items = {'Mixed Acid Loading Vs. Titer', 'Fixed Mixed Acid:  Loading Vs. pH', 'Fixed Mixed Acid: Loading Vs. Volume Ratio', 'Fixed Mixed Acid: Tabulated Results'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Mixed Acid Loading Vs. Titer';
            app.AcidConcentrationsPanel = uipanel(app.UIFigure);
            app.AcidConcentrationsPanel.Title = 'Acid Concentrations';
            app.AcidConcentrationsPanel.Visible = 'off';
            app.AcidConcentrationsPanel.SizeChangedFcn = createCallbackFcn(app, @AcidConcentrationsPanelSizeChanged, true);
            app.AceticgLEditFieldLabel = uilabel(app.AcidConcentrationsPanel);
            app.AceticgLEditFieldLabel.Text = 'Acetic (g/L)';
            app.AceticgLEditField = uieditfield(app.AcidConcentrationsPanel, 'numeric');
            app.AceticgLEditField.ValueChangedFcn = createCallbackFcn(app, @AceticgLEditFieldValueChanged, true);
            app.AceticgLEditField.Limits = [0 Inf];
            app.AceticgLEditField.Value = 10;
            app.PropionicgLEditFieldLabel = uilabel(app.AcidConcentrationsPanel);
            app.PropionicgLEditFieldLabel.Text = 'Propionic (g/L)';
            app.PropionicgLEditField = uieditfield(app.AcidConcentrationsPanel, 'numeric');
            app.PropionicgLEditField.ValueChangedFcn = createCallbackFcn(app, @PropionicgLEditFieldValueChanged, true);
            app.PropionicgLEditField.Limits = [0 Inf];
            app.PropionicgLEditField.Value = 10;
            app.ButyricgLEditFieldLabel = uilabel(app.AcidConcentrationsPanel);
            app.ButyricgLEditFieldLabel.Text = 'Butyric (g/L)';
            app.ButyricgLEditField = uieditfield(app.AcidConcentrationsPanel, 'numeric');
            app.ButyricgLEditField.ValueChangedFcn = createCallbackFcn(app, @ButyricgLEditFieldValueChanged, true);
            app.ButyricgLEditField.Limits = [0 Inf];
            app.ButyricgLEditField.Value = 10;
            app.ValericgLEditFieldLabel = uilabel(app.AcidConcentrationsPanel);
            app.ValericgLEditFieldLabel.Text = 'Valeric (g/L)';
            app.ValericgLEditField = uieditfield(app.AcidConcentrationsPanel, 'numeric');
            app.ValericgLEditField.ValueChangedFcn = createCallbackFcn(app, @ValericgLEditFieldValueChanged, true);
            app.ValericgLEditField.Limits = [0 50];
            app.ValericgLEditField.Value = 10;
            app.CaproicgLEditFieldLabel = uilabel(app.AcidConcentrationsPanel);
            app.CaproicgLEditFieldLabel.Text = 'Caproic (g/L)';
            app.CaproicgLEditField = uieditfield(app.AcidConcentrationsPanel, 'numeric');
            app.CaproicgLEditField.ValueChangedFcn = createCallbackFcn(app, @CaproicgLEditFieldValueChanged, true);
            app.CaproicgLEditField.Limits = [0 10];
            app.CaproicgLEditField.Value = 10;
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Acid Titer of Broth at Contactor Inlet (g/L)')
            ylabel(app.UIAxes2, 'Percent Recovery from Aqueous Stream (%)')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XLimMode = 'manual';
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.YLimMode = 'manual';
            app.UIAxes2.CLim = [0 1];
            app.UIAxes2.CLimMode = 'manual';
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Acid'; 'Init. AQ Conc. (g/L)'; 'EQ AQ Conc. (g/L)'; 'EQ ORG Conc. (g/L)'; 'KD'; 'pHi'; 'pHf'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ExtractionAPP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @uifigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end